classdef roiSignalProcess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ReplotButton                    matlab.ui.control.Button
        ProcessROIsfromFigureButton     matlab.ui.control.Button
        ManuallyadjustROIsButton        matlab.ui.control.Button
        ROIClusteringPanel              matlab.ui.container.Panel
        CenterclustersonpeakCheckBox    matlab.ui.control.CheckBox
        ClusterROIsizepxEditField       matlab.ui.control.EditField
        ClusterROIsizepxEditFieldLabel  matlab.ui.control.Label
        TimeseparationcutoffframesEditField  matlab.ui.control.EditField
        TimeseparationcutoffframesEditFieldLabel  matlab.ui.control.Label
        ClusterROIsButton               matlab.ui.control.Button
        PickoutregionswithpeaksPanel    matlab.ui.container.Panel
        KeepregionswithnopeaksCheckBox  matlab.ui.control.CheckBox
        CurvaturethresholdEditField     matlab.ui.control.EditField
        CurvaturethresholdEditFieldLabel  matlab.ui.control.Label
        PeakthresholdEditField          matlab.ui.control.EditField
        PeakthresholdEditFieldLabel     matlab.ui.control.Label
        CutoffsLabel                    matlab.ui.control.Label
        SVGsmoothingLabel               matlab.ui.control.Label
        WindowEditField                 matlab.ui.control.EditField
        WindowLabel                     matlab.ui.control.Label
        FindPeaksButton                 matlab.ui.control.Button
        OrderEditField                  matlab.ui.control.EditField
        OrderLabel                      matlab.ui.control.Label
        DetrendorderEditField           matlab.ui.control.EditField
        DetrendorderEditFieldLabel      matlab.ui.control.Label
        GetSignalTracesButton           matlab.ui.control.Button
        TileROIsPanel                   matlab.ui.container.Panel
        ROIshiftpxEditField             matlab.ui.control.EditField
        ROIshiftpxEditFieldLabel        matlab.ui.control.Label
        ROIsizepxEditField              matlab.ui.control.EditField
        ROIsizepxEditFieldLabel         matlab.ui.control.Label
        TileRectangleROIsButton         matlab.ui.control.Button
        LoadDataPanel                   matlab.ui.container.Panel
        SetCellBoundButton              matlab.ui.control.Button
        CellNameEditField               matlab.ui.control.EditField
        CellNameEditFieldLabel          matlab.ui.control.Label
        PostuncagingfileglobButton      matlab.ui.control.Button
        FileGlob                        matlab.ui.control.EditField
        DirectoryButton                 matlab.ui.control.Button
        DirNameField                    matlab.ui.control.EditField
        LoadButton                      matlab.ui.control.Button
        ImageControlPanel               matlab.ui.container.Panel
        ImageBrightnessSlider           matlab.ui.control.Slider
        ImageBrightnessSliderLabel      matlab.ui.control.Label
        ImageContrastSlider             matlab.ui.control.Slider
        ImageContrastSliderLabel        matlab.ui.control.Label
        ImageResetButton                matlab.ui.control.Button
    end

    properties (Access = public)
        CL % cell object with photoactivated areas
        imgH % handle to image object on the axes        
        img % raw image that is currently being plotted
        ImageAxes % axes where image is plotted
        ImageFigure % figure where image is plotted
        roicmap % colormap corresponding to current ROIs
        
        % figure and axes for plotting signal
        PlotFigure
        PlotAxes
        
        % ROI structures for those shown in the app
        ROIs
    end
    
    methods (Access = public)
        
        function updateimage(app,img)
            
            if (isempty(app.ImageFigure))
                app.ImageFigure = figure;
                app.ImageAxes = gca;
                %set(app.ImageAxes,'Position',[0 0 1 1])                
            else
                if (~isvalid(app.ImageFigure))
                    app.ImageFigure = figure;
                    app.ImageAxes = gca;
                end
            end
            
            axis(app.ImageAxes, 'image');
            if (exist('img','var'))
                app.img = img;
            end
            
            set(app.ImageFigure,'Name','Image and ROIs')
            
            % display current network object overlaid on image
            if (~isempty(app.img))
                app.imgH = imshow(app.img,'Parent',app.ImageAxes);
            end   
            title(app.CL.Name,'Interpreter','none')
            
            if ~isempty(app.CL)
                if (~isempty(app.CL.fullcellROI))
                    drawpolygon('Position',app.CL.fullcellROI.bound,...
                        'FaceAlpha',0,'InteractionsAllowed','none')
                end
            end
        end
                
        function setImageBrightness(app)
            contrast = app.ImageContrastSlider.Value;
            brightness = app.ImageBrightnessSlider.Value;
            
            app.imgH.CData = app.img* 10^contrast+brightness;
            
            app.imgH.CData(app.imgH.CData>1) = 1;
            app.imgH.CData(app.imgH.CData<0) = 0;        
        end
        
        function results = updateROIplots(app,textlabels)
            
            disp('Plotting ROIs and signal traces...')         
            if (isempty(app.PlotFigure))
                app.PlotFigure = figure;
                app.PlotAxes = gca;
                set(app.PlotFigure,'Name','ROI signal plots')
                %set(app.ImageAxes,'Position',[0 0 1 1])   
            elseif (~isvalid(app.PlotFigure))
                app.PlotFigure = figure;
                app.PlotAxes = gca;
                set(app.PlotFigure,'Name','ROI signal plots')
            end

            % include labels?
            if (~exist('textlabels','var'))
                textlabels = true;                
            end            

            % remove old ROIs from image figure
            oldROIs = findobj(app.ImageAxes,'Type','images.roi');
            delete(oldROIs);
            
            oldLabels = findobj(app.ImageAxes.Children,'Type','Text');          
            delete(oldLabels);
            
            % plot the current ROIs
            app.roicmap = jet(length(app.ROIs));
            for rc = 1:length(app.ROIs)
                roi = drawrectangle('Position',app.ROIs(rc).rect,...
                    'InteractionsAllowed','none','Parent',app.ImageAxes,'FaceAlpha',0,...
                    'Color',app.roicmap(rc,:));
                app.ROIs(rc).ROI = roi;   
                hold(app.ImageAxes,'all')
                if (textlabels)
                    text(app.ImageAxes,app.ROIs(rc).cent(1),app.ROIs(rc).cent(2),...
                        sprintf('%d',rc),'Color',app.roicmap(rc,:))
                end
            end
            hold(app.ImageAxes,'off')

            % plot signals for the current set of ROIs
            %figure(app.PlotFigure)
            if (~isempty(app.ROIs))
                if (isfield(app.ROIs(1),'avgsignal'))
                    
                regionTraces = vertcat(app.ROIs.avgsignal);

                % normalize traces
                minvals = min(regionTraces')';
                tracerange = range(regionTraces,2);
                regionTracesNorm = (regionTraces-minvals)./tracerange;

                % shift up traces to view many at once
                regionTraceshift = regionTracesNorm + (1:size(regionTraces,1))';
                tlist = 1:size(regionTraces,2);

                xvals0= linspace(-5,5,100);
                gauss0 = normpdf(xvals0)*sqrt(2*pi);


                for rc = 1:length(app.ROIs)
                    plot(app.PlotAxes, tlist,regionTraceshift(rc,:),'Color',app.roicmap(rc,:))
                    hold(app.PlotAxes,'all')
                    if (isfield(app.ROIs(1),'puffind'))
                        puffind = app.ROIs(rc).puffind;
                        plot(app.PlotAxes, tlist(puffind),regionTraceshift(rc,puffind),'o','Color',app.roicmap(rc,:))
                    end
                    if (isfield(app.ROIs(rc),'puffwidths'))
                        % plot Gaussian signal overlay
                        puffind = app.ROIs(rc).puffind;
                        signal = app.ROIs(rc).avgsignal;
                        tlist = 1:length(app.ROIs(rc).avgsignal);
                        basesignal = app.ROIs(rc).basesignal;

                        for cc= 1:length(puffind)
                            base = app.ROIs(rc).basesignal(puffind(cc));
                            w = app.ROIs(rc).puffwidths(cc);
                            gauss = gauss0*(signal(puffind(cc))-base) + base;
                            xvals = xvals0*w/2 + tlist(puffind(cc));

                            gaussshift  = (gauss-minvals(rc))/tracerange(rc)+rc;

                            baseshift = (basesignal-minvals(rc))/tracerange(rc)+rc;
                            plot(app.PlotAxes,tlist,baseshift,'Color',app.roicmap(rc,:))
                            plot(app.PlotAxes,xvals,gaussshift,'--','Color',app.roicmap(rc,:))
                        end
                    end
                    if (textlabels)
                        text(app.PlotAxes,tlist(end),regionTraceshift(rc,end),sprintf('%d',rc),'Color',app.roicmap(rc,:))
                    end
                end
                hold(app.PlotAxes,'off')
                end
            end
            app.CL.ROIs = app.ROIs;
        end

        function appstate = saveAppState(app)
            % save current state of the app (parameters) in a structure
            fields= properties(app);
            appstate = struct();
            % clear large variable
            CL.imgs = [];
            for fc = 1:length(fields)
                %fields{fc}
                propName = fields{fc};
                val = app.(propName);
                if (~isa(val,'handle'))
                    % save everything that is not a handle;
                    appstate.(propName) = val;
                elseif (isprop(val,'Value'))
                    % save everything that has a value
                    appstate.(propName) = app.(propName).Value;
                elseif (strcmp(propName,'CL'))
                    % save cell objecthandle
                    appstate.(propName) = app.CL;
                end
            end
        end

        function appstate = loadAppState(app,appstate)
            % load state of the app (parameters) from structure

            fields = fieldnames(appstate);
            for fc = 1:length(fields)
                propName = fields{fc};
                val = appstate.(propName);
                appval = app.(propName);
                if (~isa(appval,'handle'))
                    app.(propName) = val;
                elseif (isprop(appval,'Value'))
                    % save everything that has a value
                    app.(propName).Value = val;
                elseif (strcmp(propName,'CL'))
                    % load CL object
                    app.CL = appstate.CL;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            
            cellset = false;
            for vc = 1:2:length(varargin)
                switch (varargin{vc})
                    case('CL') % Cell object handle already provided
                        cellset = true;
                        app.CL = varargin{vc+1};
                    case('Directory')
                        app.DirNameField.Value = Directory;                        
                end
            end
            
            % Configure image axes
            app.ImageFigure = figure;
            app.ImageAxes = gca;
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');

            if (cellset) % cell data already provided
                app.img = app.CL.ERimg;
                app.updateimage()
                app.DirNameField.Value = app.CL.DirName;
                app.CellNameEditField.Value = app.CL.Name;

                CL = app.CL;
                % set default cell boundary if one does not exist
                if (isempty(CL.actROI.bound))
                    % auto-set 'activated region', meaningless in this case
                    CL.getActROI(struct('dodisplay',0));
                    tmproi = drawpolygon('Position',CL.actROI.bound,'Visible','off');
                    CL.actROI.mask = createMask(tmproi);
                end

                if (isempty(CL.fullcellROI))
                    cellrect = [1 1 size(CL.ERimg')];
                    CL.setCellNucROI(struct('plotimage',false,'showActROI',0,'nuccent',[1,1],'nucrad',0,'cellrect',cellrect));
                end

            end
                       
            if (cellset)
                app.updateimage(app.img)

                if (~isempty(CL.ROIs))
                    app.ROIs = CL.ROIs;
                    app.updateROIplots(true);
                end
            end
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % load in cell object from the directory and file names provided
                        
            CL = CellObjPA(app.CellNameEditField.Value);
    
            dirname = app.DirNameField.Value;
            tmp = dir([dirname,app.FileGlob.Value])
            if (isempty(tmp))
                error(sprintf('No file found %s%s',dirname,app.FileGlob.Value));
            end
            CL.loadCellData(dirname,tmp.name,'',tmp.name,tmp.name);

            CL.ERimg = double(CL.ERimg)/max(double(CL.ERimg(:)));

            % load in all images
            CL.imgs = loadImages(CL.DirName,CL.PAprefile,CL.PAfile);
            app.CL = CL;
            app.img = CL.ERimg;
            app.updateimage(app.img);
            
            
            % Make sure user didn't cancel uigetfile dialog
            %if (ischar(p))
             %  fname = [p f];
             %  updateimage(app, fname);
            %end
        end

        % Button pushed function: DirectoryButton
        function DirectoryButtonPushed(app, event)
            dirname = uigetdir();
            
            app.DirNameField.Value = dirname;
        end

        % Button pushed function: ImageResetButton
        function ImageResetButtonPushed(app, event)
            app.ImageContrastSlider.Value = 0;
            app.ImageBrightnessSlider.Value = 0;
            app.setImageBrightness();
        end

        % Value changed function: ImageContrastSlider
        function ImageContrastSliderValueChanged(app, event)
            %value = app.ImageContrastSlider.Value;
            app.setImageBrightness();    
        end

        % Value changed function: ImageBrightnessSlider
        function ImageBrightnessSliderValueChanged(app, event)
            %value = app.ImageBrightnessSlider.Value;
            app.setImageBrightness();  
        end

        % Button pushed function: SetCellBoundButton
        function SetCellBoundButtonPushed(app, event)
            figure(app.ImageFigure)
            CL = app.CL;
            
            if (isempty(CL.actROI.bound))
                % auto-set 'activated region', meaningless in this case
                CL.getActROI(struct('dodisplay',0)); 
                tmproi = drawpolygon('Position',CL.actROI.bound,'Visible','off');
                CL.actROI.mask = createMask(tmproi);
            end
            
            if (~isempty(CL.fullcellROI))
                % erase old ROI
                delete(CL.fullcellROI.ROI);
                delete(CL.nucROI.ROI)
                CL.fullcellROI = [];
            end
            
           % manually trace cell outline on top of current image
           CL.setCellNucROI(struct('plotimage',false,'showActROI',0,'nuccent',[1,1],'nucrad',0));
           
           % make displayed ROI transparent and non-adjustable
           CL.fullcellROI.ROI.FaceAlpha = 0;
           CL.fullcellROI.ROI.InteractionsAllowed = 'none';
        end

        % Button pushed function: TileRectangleROIsButton
        function TileRectangleROIsButtonPushed(app, event)
            roiwidth = str2num(app.ROIsizepxEditField.Value);
            roishift = str2num(app.ROIshiftpxEditField.Value);
            
            options = struct('minareafrac', 0.8,'dodisplay',1);
            figure(app.ImageFigure)
            disp('Tiling cell with rectangular ROIs...')
            app.roicmap = app.CL.getRectROIs(roiwidth,roishift,options);
            disp('done tiling')
            
            app.ImageAxes = findobj(app.ImageFigure,'Type','Axes');
            app.imgH = findobj(app.ImageAxes,'Type','Image')

            if ~isempty(app.PlotFigure)
                if (isvalid(app.PlotFigure))
                    lines = findobj(app.PlotAxes.Children,'Type','Line');
                    delete(lines);
                end
            end
        end

        % Button pushed function: GetSignalTracesButton
        function GetSignalTracesButtonPushed(app, event)

            if (isempty(app.CL.imgs))
                app.CL.imgs = loadImages(app.CL.DirName,app.CL.PAprefile,app.CL.PAfile);
            end

            % get ROI signals
            disp('Getting signal traces...')
            [regionTraces,imgs] = getROItraces(app.CL,0,struct(),struct('imgs',app.CL.imgs));
            disp('Done getting signal traces')
            app.ROIs = app.CL.ROIs;                       
            
            app.updateROIplots(false)            
            disp('Done plotting.')
        end

        % Button pushed function: FindPeaksButton
        function FindPeaksButtonPushed(app, event)
            % filter out regions with peaks
            
            options = struct();
            options.detrendorder = str2num(app.DetrendorderEditField.Value);
            options.order = str2num(app.OrderEditField.Value);
            options.framelen = str2num(app.WindowEditField.Value);
            options.cutscalepeak = str2num(app.PeakthresholdEditField.Value);
            options.cutscalecurvature = str2num(app.CurvaturethresholdEditField.Value);

            [keepreg, app.ROIs] = filterForPeaks(app.ROIs,options);
            
            if (~app.KeepregionswithnopeaksCheckBox.Value)
                % drop the rois with no peaks found
                app.ROIs = app.ROIs(keepreg);
            end

            % plot the updated list of ROIs
            app.updateROIplots()
        end

        % Button pushed function: ClusterROIsButton
        function ClusterROIsButtonPushed(app, event)
            minfracinside = 0.8; % minimal ROI fraction that must be inside cell
            dodisplay = true;
            

            % find clustered regions
            cutoff = str2num(app.TimeseparationcutoffframesEditField.Value);
            [peakregcent,peakregmask] = clusterROIs(app.ROIs,struct('timesepcut',cutoff));
            
            % redefine centers based on spatial peak
            centeronpeak = app.CenterclustersonpeakCheckBox.Value;
            if (centeronpeak)
                peakoptions = struct();
                peakoptions.detrendorder = str2num(app.DetrendorderEditField.Value);
                peakoptions.order = str2num(app.OrderEditField.Value);
                peakoptions.framelen = str2num(app.WindowEditField.Value);
                peakoptions.cutscalepeak = str2num(app.PeakthresholdEditField.Value);
                peakoptions.cutscalecurvature = str2num(app.CurvaturethresholdEditField.Value);
                [peakregcent,peakregmask] = spatialPeakReg(peakregmask,app.CL.imgs,peakoptions);
            end

            %% make new rectangular ROIs around region centroids            
            keepct =0;
            roiwidth = str2num(app.ClusterROIsizepxEditField.Value); % width of rectangular ROI to represent cluster
            
            plotaxes = app.ImageAxes;
            for cc = 1:size(peakregcent,1)
                x = peakregcent(cc,1)-roiwidth/2; y = peakregcent(cc,2)-roiwidth/2;
               roirect = [x y roiwidth roiwidth];
    
               roi = drawrectangle('Position',roirect,'InteractionsAllowed','none','Visible','off','Color','w','Parent',plotaxes);
               roimask = createMask(roi);
               A = sum(roimask(:));
               intersection = roimask & app.CL.fullcellROI.mask;
    
               if (sum(intersection(:))/sum(roimask(:)) > minfracinside)
                    keepct = keepct+1;
                    
                    roibound = rect2bound(roirect);
                    cent = [x+roiwidth/2,y+roiwidth/2];

                    newROI = struct('mask',roimask,'bound',roibound,'cent',cent,'rad',0,...
                        'whichrad',1,'whichth',1,'type','rect','rect',roirect);
                    if (keepct==1)
                        allROIs = [newROI];
                    else
                        allROIs(end+1) = newROI;
                    end
                    if (dodisplay)
                        roi.Visible = 'on';
                        roi.Color = [1 1 1];
                    end
                    %
                    %         hold all
                    %         text(x,y,sprintf('%d',cc),'Color','c')
               else
                   roi.delete()
               end
            end
                        
            if (~exist('allROIs','var'))
                app.ROIs = [];
            else
                app.ROIs = allROIs;
            end
            app.CL.ROIs = app.ROIs;

            % get new ROI signals
            disp('Getting signal traces...')
            [regionTraces,imgs] = getROItraces(app.CL,0,struct(),struct('imgs',app.CL.imgs));
            disp('Done getting signal traces')
            app.ROIs = app.CL.ROIs;
        end

        % Button pushed function: ManuallyadjustROIsButton
        function ManuallyadjustROIsButtonPushed(app, event)
            % enable ROIs to be manually adjusted and/or deleted
            for rc = 1:length(app.ROIs)
                roi = app.ROIs(rc).ROI;
                roi.InteractionsAllowed = 'all';
            end
        end

        % Button pushed function: ProcessROIsfromFigureButton
        function ProcessROIsfromFigureButtonPushed(app, event)
            keeproi = true(length(app.ROIs),1);
            for rc = 1:length(app.ROIs)
                roi = app.ROIs(rc).ROI;
                if (~isvalid(roi)) % has been deleted
                    keeproi(rc) = false;
                    continue
                end
                % turn off interactions
                roi.InteractionsAllowed ='none';
                
                app.ROIs(rc).rect = roi.Position;
                app.ROIs(rc).bound = rect2bound(app.ROIs(rc).rect);
                app.ROIs(rc).mask = createMask(roi);
                app.ROIs(rc).cent = mean(app.ROIs(rc).bound);
                app.ROIs(rc).type = 'rect';
            end
            app.ROIs = app.ROIs(keeproi);  
            app.ROIs = rmfield(app.ROIs,{'puffind','puffwidths','puffinteg','basesignal'})
            app.CL.ROIs = app.ROIs;
            
            disp('Getting signal traces...')
            [regionTraces,imgs] = getROItraces(app.CL,0,struct(),struct('imgs',app.CL.imgs));
            disp('Done getting signal traces')
            app.ROIs = app.CL.ROIs;
            
            disp('Plotting ROIs and signal traces...')
            
            app.updateROIplots()            
            disp('Done plotting.')
        end

        % Button pushed function: ReplotButton
        function ReplotButtonPushed(app, event)
            app.updateimage(app.CL.ERimg)

            if (~isempty(app.CL.ROIs))
                app.ROIs = app.CL.ROIs;
                app.updateROIplots(true);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Process Signal Peaks in ROIs';
            app.ImageControlPanel = uipanel(app.UIFigure);
            app.ImageControlPanel.Title = 'Image Control';
            app.ImageResetButton = uibutton(app.ImageControlPanel, 'push');
            app.ImageResetButton.ButtonPushedFcn = createCallbackFcn(app, @ImageResetButtonPushed, true);
            app.ImageResetButton.Text = 'Image Reset';
            app.ImageContrastSliderLabel = uilabel(app.ImageControlPanel);
            app.ImageContrastSliderLabel.Text = 'Image Contrast';
            app.ImageContrastSlider = uislider(app.ImageControlPanel);
            app.ImageContrastSlider.Limits = [-1 2];
            app.ImageContrastSlider.MajorTicks = [];
            app.ImageContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ImageContrastSliderValueChanged, true);
            app.ImageContrastSlider.MinorTicks = [];
            app.ImageBrightnessSliderLabel = uilabel(app.ImageControlPanel);
            app.ImageBrightnessSliderLabel.Text = 'Image Brightness';
            app.ImageBrightnessSlider = uislider(app.ImageControlPanel);
            app.ImageBrightnessSlider.Limits = [-0.6 0.6];
            app.ImageBrightnessSlider.MajorTicks = [];
            app.ImageBrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @ImageBrightnessSliderValueChanged, true);
            app.ImageBrightnessSlider.MinorTicks = [];
            app.LoadDataPanel = uipanel(app.UIFigure);
            app.LoadDataPanel.Title = 'Load Data';
            app.LoadButton = uibutton(app.LoadDataPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Cell Data';
            app.DirNameField = uieditfield(app.LoadDataPanel, 'text');
            app.DirectoryButton = uibutton(app.LoadDataPanel, 'push');
            app.DirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @DirectoryButtonPushed, true);
            app.DirectoryButton.Text = 'Directory:';
            app.FileGlob = uieditfield(app.LoadDataPanel, 'text');
            app.FileGlob.Value = '*Pb1*_ch00.tif';
            app.PostuncagingfileglobButton = uibutton(app.LoadDataPanel, 'push');
            app.PostuncagingfileglobButton.Text = 'Post-uncaging file glob:';
            app.CellNameEditFieldLabel = uilabel(app.LoadDataPanel);
            app.CellNameEditFieldLabel.Text = 'Cell Name:';
            app.CellNameEditField = uieditfield(app.LoadDataPanel, 'text');
            app.SetCellBoundButton = uibutton(app.LoadDataPanel, 'push');
            app.SetCellBoundButton.ButtonPushedFcn = createCallbackFcn(app, @SetCellBoundButtonPushed, true);
            app.SetCellBoundButton.Text = 'Set Cell Bound';
            app.TileROIsPanel = uipanel(app.UIFigure);
            app.TileROIsPanel.Title = 'Tile ROIs';
            app.TileRectangleROIsButton = uibutton(app.TileROIsPanel, 'push');
            app.TileRectangleROIsButton.ButtonPushedFcn = createCallbackFcn(app, @TileRectangleROIsButtonPushed, true);
            app.TileRectangleROIsButton.Text = 'Tile Rectangle ROIs';
            app.ROIsizepxEditFieldLabel = uilabel(app.TileROIsPanel);
            app.ROIsizepxEditFieldLabel.Text = 'ROI size (px):';
            app.ROIsizepxEditField = uieditfield(app.TileROIsPanel, 'text');
            app.ROIsizepxEditField.Value = '20';
            app.ROIshiftpxEditFieldLabel = uilabel(app.TileROIsPanel);
            app.ROIshiftpxEditFieldLabel.Text = 'ROI shift (px):';
            app.ROIshiftpxEditField = uieditfield(app.TileROIsPanel, 'text');
            app.ROIshiftpxEditField.Value = '20';
            app.GetSignalTracesButton = uibutton(app.UIFigure, 'push');
            app.GetSignalTracesButton.ButtonPushedFcn = createCallbackFcn(app, @GetSignalTracesButtonPushed, true);
            app.GetSignalTracesButton.Text = 'Get Signal Traces';
            app.PickoutregionswithpeaksPanel = uipanel(app.UIFigure);
            app.PickoutregionswithpeaksPanel.Title = 'Pick out regions with peaks';
            app.DetrendorderEditFieldLabel = uilabel(app.PickoutregionswithpeaksPanel);
            app.DetrendorderEditFieldLabel.Text = 'Detrend order';
            app.DetrendorderEditField = uieditfield(app.PickoutregionswithpeaksPanel, 'text');
            app.DetrendorderEditField.Value = '10';
            app.OrderLabel = uilabel(app.PickoutregionswithpeaksPanel);
            app.OrderLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.PickoutregionswithpeaksPanel, 'text');
            app.OrderEditField.Value = '3';
            app.FindPeaksButton = uibutton(app.PickoutregionswithpeaksPanel, 'push');
            app.FindPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @FindPeaksButtonPushed, true);
            app.FindPeaksButton.Text = 'Find Peaks';
            app.WindowLabel = uilabel(app.PickoutregionswithpeaksPanel);
            app.WindowLabel.Text = 'Window';
            app.WindowEditField = uieditfield(app.PickoutregionswithpeaksPanel, 'text');
            app.WindowEditField.Value = '15';
            app.SVGsmoothingLabel = uilabel(app.PickoutregionswithpeaksPanel);
            app.SVGsmoothingLabel.Text = 'SVG smoothing:';
            app.CutoffsLabel = uilabel(app.PickoutregionswithpeaksPanel);
            app.CutoffsLabel.Text = 'Cutoffs:';
            app.PeakthresholdEditFieldLabel = uilabel(app.PickoutregionswithpeaksPanel);
            app.PeakthresholdEditFieldLabel.Text = 'Peak threshold';
            app.PeakthresholdEditField = uieditfield(app.PickoutregionswithpeaksPanel, 'text');
            app.PeakthresholdEditField.Value = '1';
            app.CurvaturethresholdEditFieldLabel = uilabel(app.PickoutregionswithpeaksPanel);
            app.CurvaturethresholdEditFieldLabel.Text = 'Curvature threshold';
            app.CurvaturethresholdEditField = uieditfield(app.PickoutregionswithpeaksPanel, 'text');
            app.CurvaturethresholdEditField.Value = '1';
            app.KeepregionswithnopeaksCheckBox = uicheckbox(app.PickoutregionswithpeaksPanel);
            app.KeepregionswithnopeaksCheckBox.Text = {'Keep regions'; 'with no peaks'};
            app.ROIClusteringPanel = uipanel(app.UIFigure);
            app.ROIClusteringPanel.Title = 'ROI Clustering';
            app.ClusterROIsButton = uibutton(app.ROIClusteringPanel, 'push');
            app.ClusterROIsButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterROIsButtonPushed, true);
            app.ClusterROIsButton.Text = 'Cluster ROIs';
            app.TimeseparationcutoffframesEditFieldLabel = uilabel(app.ROIClusteringPanel);
            app.TimeseparationcutoffframesEditFieldLabel.Text = {'Time separation '; ' cutoff (frames):'};
            app.TimeseparationcutoffframesEditField = uieditfield(app.ROIClusteringPanel, 'text');
            app.TimeseparationcutoffframesEditField.Value = '5';
            app.ClusterROIsizepxEditFieldLabel = uilabel(app.ROIClusteringPanel);
            app.ClusterROIsizepxEditFieldLabel.Text = {'Cluster ROI size (px)'; ''};
            app.ClusterROIsizepxEditField = uieditfield(app.ROIClusteringPanel, 'text');
            app.ClusterROIsizepxEditField.Value = '20';
            app.CenterclustersonpeakCheckBox = uicheckbox(app.ROIClusteringPanel);
            app.CenterclustersonpeakCheckBox.Text = 'Center clusters on peak';
            app.CenterclustersonpeakCheckBox.Value = true;
            app.ManuallyadjustROIsButton = uibutton(app.UIFigure, 'push');
            app.ManuallyadjustROIsButton.ButtonPushedFcn = createCallbackFcn(app, @ManuallyadjustROIsButtonPushed, true);
            app.ManuallyadjustROIsButton.Text = 'Manually adjust ROIs';
            app.ProcessROIsfromFigureButton = uibutton(app.UIFigure, 'push');
            app.ProcessROIsfromFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessROIsfromFigureButtonPushed, true);
            app.ProcessROIsfromFigureButton.Text = 'Process ROIs from Figure';
            app.ReplotButton = uibutton(app.UIFigure, 'push');
            app.ReplotButton.ButtonPushedFcn = createCallbackFcn(app, @ReplotButtonPushed, true);
            app.ReplotButton.Text = 'Replot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = roiSignalProcess(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end